classdef rhwa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        aSliderLabel   matlab.ui.control.Label
        aSlider        matlab.ui.control.Slider
        zSlider        matlab.ui.control.Slider
        ySlider        matlab.ui.control.Slider
        Slider2Label   matlab.ui.control.Label
        timeslid       matlab.ui.control.Slider
        symButton      matlab.ui.control.Button
        alltimeButton  matlab.ui.control.Button
        bLabel         matlab.ui.control.Label
        cLabel         matlab.ui.control.Label
        dSliderLabel   matlab.ui.control.Label
        dSlider        matlab.ui.control.Slider
    end

    
    properties (Access = private)
        flux
        Head % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function launc(app)
            a = load('G:\My Drive\NeoLab\Connectivity\Atlas\4D Neonatal Atlas - UCL\30-weeks\HeadVolumeMesh_30weeks.mat');
            app.Head = a.HeadVolumeMesh_30weeks;
            app.flux = demo_mmclab_basic(app.Head);
        end

        % Value changed function: aSlider, dSlider, timeslid, 
        % ySlider, zSlider
        function xch(app, event)
            sample = round(app.timeslid.Value) ;
            fetta = [app.aSlider.Value app.ySlider.Value app.zSlider.Value app.dSlider.Value];
            plots  = log10(abs(app.flux.data(1:size(app.Head.node,1),sample+1)));
            [cutpos,cutvalue,~,~] = qmeshcut(app.Head.elem(:,1:4),app.Head.node(:,1:3),plots,fetta);
            scatter3( app.UIAxes, cutpos(:,1),cutpos(:,2),cutpos(:,3),8,cutvalue);
            app.UIAxes.CameraPosition = 1000.*fetta(1:3);
            app.UIAxes.CameraTarget = [0 0 0];
        end

        % Button pushed function: symButton
        function res(app, event)
            app.flux = demo_mmclab_basic(app.Head);
            fetta = [app.aSlider.Value app.ySlider.Value app.zSlider.Value app.dSlider.Value];
            plots   = log10(sum(abs(app.flux.data(1:size(app.Head.node,1),:)),2));
            [cutpos,cutvalue,~,~] = qmeshcut(app.Head.elem(:,1:4),app.Head.node(:,1:3),plots,fetta);
             scatter3( app.UIAxes, cutpos(:,1),cutpos(:,2),cutpos(:,3),8,cutvalue);
            app.UIAxes.CameraPosition = 1000.*fetta(1:3);
            app.UIAxes.CameraTarget = [0 0 0];
        end

        % Button pushed function: alltimeButton
        function all(app, event)
            
            fetta = [app.aSlider.Value app.ySlider.Value app.zSlider.Value app.dSlider.Value];
            plots  = log10(sum(abs(app.flux.data(1:size(app.Head.node,1),:)),2));
            [cutpos,cutvalue,~,~] = qmeshcut(app.Head.elem(:,1:4),app.Head.node(:,1:3),plots,fetta);
            scatter3( app.UIAxes, cutpos(:,1),cutpos(:,2),cutpos(:,3),8,cutvalue);
            app.UIAxes.CameraPosition = 1000.*fetta(1:3);
            app.UIAxes.CameraTarget = [0 0 0];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @xch, true);
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @xch, true);
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @xch, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.timeslid = uislider(app.UIFigure);
            app.timeslid.ValueChangedFcn = createCallbackFcn(app, @xch, true);
            app.symButton = uibutton(app.UIFigure, 'push');
            app.symButton.ButtonPushedFcn = createCallbackFcn(app, @res, true);
            app.symButton.Text = 'sym';
            app.alltimeButton = uibutton(app.UIFigure, 'push');
            app.alltimeButton.ButtonPushedFcn = createCallbackFcn(app, @all, true);
            app.alltimeButton.Text = 'all time';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = 'c';
            app.dSliderLabel = uilabel(app.UIFigure);
            app.dSliderLabel.Text = 'd';
            app.dSlider = uislider(app.UIFigure);
            app.dSlider.ValueChangedFcn = createCallbackFcn(app, @xch, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = rhwa

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @launc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end